classdef BLE_GUI_FLUIGENT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        FunzionamentoPanel           matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        PortateerogateTab            matlab.ui.container.Tab
        GridLayout4_2                matlab.ui.container.GridLayout
        VolumiErogatiPanel           matlab.ui.container.Panel
        GridLayout12                 matlab.ui.container.GridLayout
        BatchcompletoCheckBox        matlab.ui.control.CheckBox
        STOPButton                   matlab.ui.control.Button
        TimeEditField                matlab.ui.control.NumericEditField
        TimeGauge                    matlab.ui.control.LinearGauge
        TempoLabel                   matlab.ui.control.Label
        Volume3EditField             matlab.ui.control.NumericEditField
        Volume2EditField             matlab.ui.control.NumericEditField
        Volume1EditField             matlab.ui.control.NumericEditField
        ResetMassButton              matlab.ui.control.Button
        VolumetotGauge               matlab.ui.control.LinearGauge
        VolumetotGaugeLabel          matlab.ui.control.Label
        Volume2Gauge                 matlab.ui.control.LinearGauge
        Volume2GaugeLabel            matlab.ui.control.Label
        Volume1Gauge                 matlab.ui.control.LinearGauge
        Volume1GaugeLabel            matlab.ui.control.Label
        VolumimassimiPanel           matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        ImpostavolumimassimiButton   matlab.ui.control.Button
        MassaTotEditField            matlab.ui.control.NumericEditField
        MassaTotEditFieldLabel       matlab.ui.control.Label
        Massa2EditField              matlab.ui.control.NumericEditField
        Massa2EditFieldLabel         matlab.ui.control.Label
        Massa1EditField              matlab.ui.control.NumericEditField
        Massa1EditFieldLabel         matlab.ui.control.Label
        GraficoTab                   matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        Regressione                  matlab.ui.container.Tab
        GridLayout10                 matlab.ui.container.GridLayout
        CLOSELOOPVALUESVOLTAGEKpeKietdtKpdedteQreadQsetLabel  matlab.ui.control.Label
        OPENLOOPVALUESVOLTAGEQSETalfaBetaLabel  matlab.ui.control.Label
        SendPIDvaluesButton          matlab.ui.control.Button
        Alfa                         matlab.ui.control.NumericEditField
        alfaLabel                    matlab.ui.control.Label
        Beta                         matlab.ui.control.NumericEditField
        betaLabel                    matlab.ui.control.Label
        kd                           matlab.ui.control.NumericEditField
        alfaLabel_4                  matlab.ui.control.Label
        ki                           matlab.ui.control.NumericEditField
        alfaLabel_3                  matlab.ui.control.Label
        kp                           matlab.ui.control.NumericEditField
        alfaLabel_2                  matlab.ui.control.Label
        SendDatasRegressionButton    matlab.ui.control.Button
        Calibrazione                 matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        flussimetro2Panel            matlab.ui.container.Panel
        GridLayout8_2                matlab.ui.container.GridLayout
        K2EditField                  matlab.ui.control.NumericEditField
        K2EditFieldLabel             matlab.ui.control.Label
        FattoredicorrezioneButton_2  matlab.ui.control.Button
        BilanciaEditField_2          matlab.ui.control.NumericEditField
        BilanciaEditField_2Label     matlab.ui.control.Label
        StimatoEditField_2           matlab.ui.control.NumericEditField
        StimatoEditField_2Label      matlab.ui.control.Label
        switch_pompa                 matlab.ui.control.RockerSwitch
        chronometerGauge             matlab.ui.control.LinearGauge
        CalibraButton_2              matlab.ui.control.Button
        flussimetro1Panel            matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        K1EditField                  matlab.ui.control.NumericEditField
        K1EditFieldLabel             matlab.ui.control.Label
        FattoredicorrezioneButton    matlab.ui.control.Button
        BilanciaEditField            matlab.ui.control.NumericEditField
        BilanciaEditFieldLabel       matlab.ui.control.Label
        StimatoEditField             matlab.ui.control.NumericEditField
        StimatoEditFieldLabel        matlab.ui.control.Label
        ExcelTab                     matlab.ui.container.Tab
        GridLayout14                 matlab.ui.container.GridLayout
        AcquisizioneDati             matlab.ui.control.StateButton
        XLSButton                    matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        LaunchPanel                  matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        SensorModeSwitch             matlab.ui.control.RockerSwitch
        Lamp                         matlab.ui.control.Lamp
        SerialeTextArea              matlab.ui.control.TextArea
        SerialeTextAreaLabel         matlab.ui.control.Label
        FunzioneSwitch               matlab.ui.control.RockerSwitch
        portate_2                    matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        FlowRateRatioEditField       matlab.ui.control.NumericEditField
        FlowRateRatioLabel           matlab.ui.control.Label
        TotalFlowRateEditField       matlab.ui.control.NumericEditField
        TotalFlowRateLabel           matlab.ui.control.Label
        portate                      matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Portata2EditField            matlab.ui.control.NumericEditField
        Flusso2Label                 matlab.ui.control.Label
        Portata1EditField            matlab.ui.control.NumericEditField
        Flusso1Label                 matlab.ui.control.Label
        StartProductionButton        matlab.ui.control.Button
        ConnessionePanel             matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        ConnessioneButton            matlab.ui.control.StateButton
        listaBLE                     matlab.ui.control.DropDown
        ListaBleLabel                matlab.ui.control.Label
    end

    properties (Access = private)
        % Dispositivi e caratteristiche BLE
        bleDevice               % Oggetto del dispositivo BLE
        bleCharacteristic       % Caratteristica BLE per comunicare con il dispositivo
        timerObj               % Oggetto del timer per il campionamento periodico
        values                 % Variabile per memorizzare i valori ricevuti dal BLE
        bufferSize = 15;       % Numero di campioni da mantenere nel buffer
        timeBuffer = nan(15,1);% Buffer per i tempi di campionamento
        data = nan(15,7);      % Buffer unico per i dati (X, Y, Z, ecc.)
        tStart;                % Tempo di inizio per il campionamento
        tPause;
        mass=zeros(3,1);
        tPrev = 0;  % Per salvare il tempo del ciclo precedente
        flag=true;
        timerObjCal;
        calibration=false;
        t_Tot=0;
        t0=0;
        pid_logic=0;
        logging;
        datasxls=zeros(1000,5);
        % Description
    end

    methods (Access = private)

        function scan_ble(app)
            % Mostra messaggio iniziale nell'interfaccia
            app.ListaBleLabel.Text = "Scansione in corso...";

            while true

                porte_ble = blelist("Name","Ard");

                if ~isempty(porte_ble)
                    break;
                end

                % Aggiorna il messaggio ogni ciclo, se vuoi
                app.ListaBleLabel.Text = "Nessun dispositivo trovato, riprovo...";
                pause(1);
            end

            % Aggiorna la lista dei dispositivi BLE nell'interfaccia
            try
                app.listaBLE.Items = porte_ble.Name;
                app.ListaBleLabel.Text = "Dispositivi trovati!";
            catch
                app.ListaBleLabel.Text = "Errore durante l'aggiornamento BLE.";
            end
        end

        % Funzione per stabilire la connessione con il dispositivo BLE
        function connection(app)
            deviceName = "ArduinoARD";  % Nome del dispositivo BLE da connettere (può essere anche l'indirizzo MAC)
            serviceUUID = "917649A0-D98E-11E5-9EEC-0002A5D5C51B";  % UUID del servizio BLE
            characteristicUUID = "917649A1-D98E-11E5-9EEC-0002A5D5C51B";  % UUID della caratteristica BLE

            % Inizializza variabili per i dispositivi BLE e il timer
            app.timerObj = [];
            app.bleDevice = [];
            app.bleCharacteristic = [];

            try
                % Abilita il pannello dell'interfaccia utente per la connessione
                app.FunzionamentoPanel.Enable = "on";
                % Connetti al dispositivo BLE e ottieni la caratteristica
                app.bleDevice = ble(deviceName);
                app.bleCharacteristic = characteristic(app.bleDevice, serviceUUID, characteristicUUID);

                % Inizia un cronometro per il tempo di connessione
                app.tStart = tic;
                % Inizializza i buffer per i dati
                app.timeBuffer = nan(1, app.bufferSize);
                app.data = nan(app.bufferSize,7);
                % Crea un timer per la lettura periodica dei dati
                app.timerObj = timer(...
                    'ExecutionMode', 'fixedSpacing', ...      % Imposta l'esecuzione fissa del timer
                    'Period', 0.01, ...                       % Frequenza di campionamento 0.01 secondi
                    'TimerFcn', @(~,~) readData(app));       % Funzione da chiamare per leggere i dati
                start(app.timerObj);                        % Avvia il timer

            catch
                % Se c'è un errore durante la connessione, mostra un avviso
                uialert(app.UIFigure, 'Connessione non riuscita.', 'Errore');
            end
        end

        % Funzione per disconnettere il dispositivo BLE
        function disconnection(app)
            if(app.bleDevice.Connected)
            
                try

                    ble_sendq(app,0,0);

                    % Disabilita il pannello dell'interfaccia utente durante la disconnessione
                    app.FunzionamentoPanel.Enable = "off";
                    % Ferma e cancella il timer
                    stop(app.timerObj);
                    delete(app.timerObj);

                    % Pulisce le variabili relative al BLE
                    app.timerObj = [];
                    app.bleDevice = [];
                    app.bleCharacteristic = [];

                catch
                    % Se c'è un errore durante la disconnessione, mostra un avviso
                    uialert(app.UIFigure, 'Errore durante disconnessione.', 'Errore');
                end

            end
        end

        % Funzione per leggere i dati periodicamente dal dispositivo BLE


        function readData(app)
            if(app.bleDevice.Connected) %%  pressed
                app.Lamp.Color=[0.39,0.83,0.07];

            else %% not pressed
                app.Lamp.Color=[1.00,0.41,0.16];
                app.timerObj = [];
            end

            try
                % Leggi i dati dalla caratteristica BLE
                raw = read(app.bleCharacteristic);

                % Controlla che siano arrivati esattamente 7 valori di tipo 'single' (float a 32 bit),
                % ciascuno dei quali occupa 4 byte → 7 * 4 = 28 byte totali.
                % Solo in questo caso possiamo fare il 'typecast' in modo sicuro.
                if length(raw) == 28  % 4 byte per ciascun float * 7valori (VOLT, q1, pwm1, q2, pwm2,f1,f2)
                    app.values = typecast(uint8(raw), 'single');  % Converte i dati raw in tipo single
                else
                    % Se i dati non sono nel formato atteso, non fare nulla
                end
            catch
                % Se c'è un errore durante la lettura, non fare nulla
            end
            %
            % V = app.values;  % Assegna i valori letti alla variabile 'V'

            try
                %disp(app.values)
                % Estrai i singoli valori (esempio, puoi usare tutti o alcuni dei dati letti)
                % random = V(1);  % Se vuoi usare il primo valore, decommenta
                q1 =  app.values(2);        % Flusso 1
                q2 = app.values(4);        % Flusso 2
                

                if  strcmp(app.SensorModeSwitch.Value,app.SensorModeSwitch.Items{1})
                    f1=q1;% f1= app.values(6);
                    f2=q2;
                else
                    f1= app.values(6);
                    f2=app.values(7);

                end


            if strcmp(app.FunzioneSwitch.Value,app.FunzioneSwitch.Items{1})
                app.portate.Enable="on";
                app.portate_2.Enable="off";

            else
                app.portate.Enable="off";
                app.portate_2.Enable="on";
            end
                % pwm2 = V(5);    % pwm per motore 2, se vuoi usarlo

                % Aggiorna i gauge dell'interfaccia utente con i valori

            catch
                % Se c'è un errore nell'assegnazione dei valori, imposta dei valori di default

            end

            %
            % Aggiorna la TextArea con i valori ricevuti
            app.SerialeTextArea.Value = num2str(app.values);

            % Calcola il tempo corrente
            tNow = toc(app.tStart);

            % Calcolo intervallo di tempo
            if app.tPrev == 0
                deltaT = 0;  % Primo campione, non possiamo calcolare differenza
            else
                deltaT = tNow - app.tPrev;
            end
            app.tPrev = tNow;


            % Integrale (somma pesata per intervallo di tempo)
            app.mass(1) = app.mass(1) + f1/60 * deltaT;
            app.mass(2) = app.mass(2) + f2/60 * deltaT;
            app.mass(3)=app.mass(1)+app.mass(2);


                app.t_Tot=app.tPrev-app.tPause;

            if (app.mass(3)>app.MassaTotEditField.Value && (app.BatchcompletoCheckBox.Value==false))
                ble_sendq(app,0,0);
                app.t_Tot=app.tPrev-app.tPause;
                app.ResetMassButton.Text=string(app.t_Tot);

                %disp((app.tPrev-app.tPause));
                app.BatchcompletoCheckBox.Value=true;

            end


            if(app.calibration==true)
                app.chronometerGauge.Value=app.tPrev-app.tPause;

                if(app.tPrev-app.tPause>60)
                    disp(app.t_Tot);
                    ble_sendq(app,0,0);
                    app.t_Tot=app.tPrev-app.tPause;

                    app.CalibraButton_2.Text=string(app.t_Tot);

                    %disp((app.tPrev-app.tPause));
                    app.calibration=false;

                    app.StimatoEditField.Value=app.mass(1);
                    app.Stimato2EditField.Value=app.mass(2);

                end
            end


            app.Volume1Gauge.Value=app.mass(1);
            app.Volume1EditField.Value=app.mass(1);

            app.Volume2Gauge.Value=app.mass(2);
            app.Volume2EditField.Value=app.mass(2);

            app.VolumetotGauge.Value=app.mass(3);
            app.Volume3EditField.Value=app.mass(3);
            if q1~=0 || q2~=0
            app.TimeGauge.Value=app.t_Tot;
            app.TimeEditField.Value=app.t_Tot;
           
            end

            % Aggiorna i buffer con uno shift circolare per mantenere solo i dati recenti
            app.timeBuffer = circshift(app.timeBuffer, -1);
            app.timeBuffer(end) = app.tPrev;


            %%
            try

                % Aggiorna i dati

                app.data = circshift(app.data, [-1, 0]);
                app.data( end,:)=app.values;
            catch
            end
            % Pulisce i NaN per il grafico
            validIdx = ~isnan(app.timeBuffer);

            % Plot dei dati ricevuti
            plot(app.UIAxes, ...
                app.timeBuffer(validIdx), app.data( validIdx,1), '-o', ...
                app.timeBuffer(validIdx), app.data( validIdx,2), '-o', ...
                app.timeBuffer(validIdx), app.data( validIdx,4), '-o', ...
                app.timeBuffer(validIdx), app.data( validIdx,6), '-o', ...
                app.timeBuffer(validIdx), app.data( validIdx,7), '-o');
            xlabel(app.UIAxes, 'Tempo (s)');
            ylabel(app.UIAxes, 'Valore');
            legend(app.UIAxes, {'Random(A0)', 'q1', 'q2', 'f1', 'f2',}, 'Location', 'eastoutside');
            drawnow;  % Aggiorna il grafico

            if(app.logging)
                %d=[app.timeBuffer(end),app.data(end,:)];
                app.dataxls = circshift(app.dataxls, [-1, 0]);
                app.dataxls( end,:)=[app.values];
            end

            %             x=app.values(2);
            %             t=app.timeBuffer(validIdx);
            %            % dt = mean(diff(t));          % intervallo di campionamento medio
            %             Fs = 1/100;                   % frequenza di campionamento
            %
            %             % Lunghezza del segnale
            %             N = 15;%length(x);               % numero di campioni
            %
            %             % FFT
            %             X = fft(x);                  % trasformata di Fourier
            %             f = (0:N-1)*(Fs/N);          % vettore frequenze
            %
            %             % Spettro unilatero normalizzato
            %             magnitude = abs(X)/N;        % modulo FFT normalizzato
            %             half = floor(N/2)+1;         % metà spettro (fino a Nyquist)
            %
            %             % Plot dello spettro
            %             plot(app.UIAxesFFT,f(1:half), magnitude(1:half), 'b');
            % drawnow;


        end

        function selection_datas(app)

            if strcmp(app.FunzioneSwitch.Value,app.FunzioneSwitch.Items{1})
                app.portate.Enable="on";
                app.portate_2.Enable="off";

            else
                app.portate.Enable="off";
                app.portate_2.Enable="on";
            end
        end

        function ble_send(app,valore)
            if isnan(valore)
                %uialert(app.UIFigure, 'Inserisci un numero valido', 'Errore');
                app.StartProductionButton.Text="non valido";
                return;
            end
            bytesToSend = typecast(valore, 'uint8');
            try
                write(app.bleCharacteristic, bytesToSend)%, "withoutResponse");
            catch
                uialert(app.UIFigure, 'Errore durante l''invio dei dati.', 'Errore BLE');
            end
        end

        function ble_sendq(app,q1,q2)
           valore =single([0 q1 q2]);
           ble_send(app,valore);
        end


        function ble_sendControl(app,setting_control,alfa,beta,gamma)
            valore =single([setting_control alfa beta gamma]);
            ble_send(app,valore);
        end


        function send_datas(app) %SELEZIONE DELLE PORTATE DA GUI
            if strcmp(app.FunzioneSwitch.Value,app.FunzioneSwitch.Items{1})

                q1=app.Portata1EditField.Value;
                q2=app.Portata2EditField.Value;
                if(q2==0)

                    TFR=(q1+q2);
                    FRR=inf;
                    app.TotalFlowRateEditField.Value= TFR;
                    app.FlowRateRatioEditField.Value= FRR;


                else
                    TFR=(q1+q2);
                    FRR=q1/q2;
                    app.TotalFlowRateEditField.Value= TFR;
                    app.FlowRateRatioEditField.Value= FRR;
                end
            else
                TFR=app.TotalFlowRateEditField.Value;
                FRR=app.FlowRateRatioEditField.Value;
                q2 = TFR / (FRR + 1);       % Portata 2
                q1 = TFR - q2;              % Portata 1, oppure: Q1 = FRR * Q2;
                app.Portata1EditField.Value= q1;
                app.Portata2EditField.Value= q2;
            end
            ble_sendq(app,q1,q2);


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            scan_ble(app);
            selection_datas(app)

        end

        % Button pushed function: StartProductionButton
        function StartProductionButtonPushed(app, event)

            app.mass=zeros(3,1);
            app.tPause = toc(app.tStart);

            send_datas(app);

        end

        % Drop down opening function: listaBLE
        function listaBLEDropDownOpening(app, event)
            scan_ble(app);

        end

        % Value changed function: ConnessioneButton
        function ConnessioneButtonValueChanged(app, event)
            value = app.ConnessioneButton.Value;
            if(value==1) %%  pressed
                app.ConnessioneButton.BackgroundColor=[0.39,0.83,0.07];
                connection(app);

            else %% not pressed
                disconnection(app);
                app.ConnessioneButton.BackgroundColor=[1.00,0.41,0.16];

            end
        end

        % Value changed function: FunzioneSwitch
        function FunzioneSwitchValueChanged2(app, event)
            selection_datas(app);

        end

        % Button pushed function: ImpostavolumimassimiButton
        function ImpostavolumimassimiButtonPushed(app, event)

            % Imposta il range del gauge
            app.Volume1Gauge.Limits = [0 app.Massa1EditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.Volume1Gauge.MajorTicks =0:5:app.Massa1EditField.Value;


            % Imposta il range del gauge
            app.Volume2Gauge.Limits = [0 app.Massa2EditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.Volume2Gauge.MajorTicks =0:5:app.Massa2EditField.Value;


            % Imposta il range del gauge
            app.VolumetotGauge.Limits = [0 app.MassaTotEditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.VolumetotGauge.MajorTicks =0:5:app.MassaTotEditField.Value;


        end

        % Button pushed function: ResetMassButton
        function ResetMassButtonPushed(app, event)

            app.BatchcompletoCheckBox.Value=false;
            app.mass=zeros(3,1);

            % Imposta il range del gauge
            try
            app.TimeGauge.Limits = [0   60*app.MassaTotEditField.Value/app.TotalFlowRateEditField.Value];
            catch

            app.TimeGauge.Limits = [0   100];
            end
            % Crea 5 tick tra 0 e maxValue
      %      app.TimeGauge.MajorTicks =0:5:  app.TimeGauge.Value;

            app.tPause = toc(app.tStart);
            send_datas(app);
        end

        % Value changed function: Massa1EditField
        function Massa1EditFieldValueChanged(app, event)
            %value = app.Massa1EditField.Value;

            % Imposta il range del gauge
            app.Volume1Gauge.Limits = [0 app.Massa1EditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.Volume1Gauge.MajorTicks =0:5:app.Massa1EditField.Value;

        end

        % Value changed function: Massa2EditField
        function Massa2EditFieldValueChanged(app, event)
            %value = app.Massa2EditField.Value;
            % Imposta il range del gauge
            app.Volume2Gauge.Limits = [0 app.Massa2EditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.Volume2Gauge.MajorTicks =0:5:app.Massa2EditField.Value;


        end

        % Value changed function: MassaTotEditField
        function MassaTotEditFieldValueChanged(app, event)
            %value = app.MassaTotEditField.Value;


            % Imposta il range del gauge
            app.VolumetotGauge.Limits = [0 app.MassaTotEditField.Value];

            % Crea 5 tick tra 0 e maxValue
            app.VolumetotGauge.MajorTicks =0:5:app.MassaTotEditField.Value;
        end

        % Button pushed function: CalibraButton_2
        function CalibraButton_2Pushed(app, event)


            app.BatchcompletoCheckBox.Value=false;
            app.mass=zeros(3,1);
            app.calibration=true;

            if strcmp(app.switch_pompa.Value,app.switch_pompa.Items{1})
                q1=30;
                q2=0;

            else
                q1=0;

                q2=30;
            end
            app.MassaTotEditField.Value=100;

            try

                app.tPause = toc(app.tStart);
                ble_sendq(app,q1,q2)

                app.CalibraButton_2.Text=string(app.t_Tot);
            catch

                % app.tPause = toc(app.tStart);
            end

        end

        % Button pushed function: FattoredicorrezioneButton
        function FattoredicorrezioneButtonPushed(app, event)
            try
                app.StimatoEditField_2Label.Value=app.StimatoEditField.Value/app.BilanciaEditField.Value;
            catch
                uialert(app.UIFigure, 'Non va bene', 'Errore masse');
            end

        end

        % Button pushed function: SendDatasRegressionButton
        function SendDatasRegressionButtonPushed(app, event)
            alfa=app.Alfa.Value;
            beta=app.Beta.Value;
            ble_sendControl(app,1,alfa,beta,0);
        end

        % Button pushed function: SendPIDvaluesButton
        function SendPIDvaluesButtonPushed(app, event)
            alfa=app.kp.Value;
            beta=app.ki.Value;
            gamma=app.ki.Value;
            ble_sendControl(app,2,alfa,beta,gamma);
        end

        % Callback function
        function AcquisizioneDatiValueChanged(app, event)
            app.logging = app.AcquisizioneDati.Value;
            app.datasxls=zeros(1000,5);
        end

        % Button pushed function: XLSButton
        function XLSButtonPushed(app, event)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                            ble_sendq(app,0,0);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConnessionePanel = uipanel(app.GridLayout);
            app.ConnessionePanel.Title = 'Connessione';
            app.GridLayout5 = uigridlayout(app.ConnessionePanel);
            app.ListaBleLabel = uilabel(app.GridLayout5);
            app.ListaBleLabel.Text = 'Lista Ble';
            app.listaBLE = uidropdown(app.GridLayout5);
            app.listaBLE.Items = {'BLE LIST'};
            app.listaBLE.DropDownOpeningFcn = createCallbackFcn(app, @listaBLEDropDownOpening, true);
            app.listaBLE.Value = 'BLE LIST';
            app.ConnessioneButton = uibutton(app.GridLayout5, 'state');
            app.ConnessioneButton.ValueChangedFcn = createCallbackFcn(app, @ConnessioneButtonValueChanged, true);
            app.ConnessioneButton.Text = 'Connessione';
            app.FunzionamentoPanel = uipanel(app.GridLayout);
            app.FunzionamentoPanel.Enable = 'off';
            app.FunzionamentoPanel.Title = 'Funzionamento';
            app.GridLayout2 = uigridlayout(app.FunzionamentoPanel);
            app.LaunchPanel = uipanel(app.GridLayout2);
            app.LaunchPanel.Title = 'Launch';
            app.GridLayout6 = uigridlayout(app.LaunchPanel);
            app.StartProductionButton = uibutton(app.GridLayout6, 'push');
            app.StartProductionButton.ButtonPushedFcn = createCallbackFcn(app, @StartProductionButtonPushed, true);
            app.StartProductionButton.Text = {'Start '; 'Production'};
            app.portate = uipanel(app.GridLayout6);
            app.GridLayout3 = uigridlayout(app.portate);
            app.Flusso1Label = uilabel(app.GridLayout3);
            app.Flusso1Label.Text = 'Portata 1';
            app.Portata1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Flusso2Label = uilabel(app.GridLayout3);
            app.Flusso2Label.Text = 'Portata 2';
            app.Portata2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.portate_2 = uipanel(app.GridLayout6);
            app.GridLayout3_2 = uigridlayout(app.portate_2);
            app.TotalFlowRateLabel = uilabel(app.GridLayout3_2);
            app.TotalFlowRateLabel.Text = 'Total Flow Rate ';
            app.TotalFlowRateEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.FlowRateRatioLabel = uilabel(app.GridLayout3_2);
            app.FlowRateRatioLabel.Text = 'Flow Rate Ratio';
            app.FlowRateRatioEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.FunzioneSwitch = uiswitch(app.GridLayout6, 'rocker');
            app.FunzioneSwitch.Items = {'Portate', 'TFR - FRR'};
            app.FunzioneSwitch.ValueChangedFcn = createCallbackFcn(app, @FunzioneSwitchValueChanged2, true);
            app.FunzioneSwitch.Value = 'Portate';
            app.SerialeTextAreaLabel = uilabel(app.GridLayout6);
            app.SerialeTextAreaLabel.Text = 'Seriale';
            app.SerialeTextArea = uitextarea(app.GridLayout6);
            app.SerialeTextArea.Editable = 'off';
            app.Lamp = uilamp(app.GridLayout6);
            app.SensorModeSwitch = uiswitch(app.GridLayout6, 'rocker');
            app.SensorModeSwitch.Items = {'Senza Sensore', 'Sensore'};
            app.SensorModeSwitch.Enable = 'off';
            app.SensorModeSwitch.Value = 'Senza Sensore';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PortateerogateTab = uitab(app.TabGroup);
            app.PortateerogateTab.Title = 'Portate erogate';
            app.GridLayout4_2 = uigridlayout(app.PortateerogateTab);
            app.VolumimassimiPanel = uipanel(app.GridLayout4_2);
            app.VolumimassimiPanel.Title = 'Volumi massimi';
            app.GridLayout11 = uigridlayout(app.VolumimassimiPanel);
            app.Massa1EditFieldLabel = uilabel(app.GridLayout11);
            app.Massa1EditFieldLabel.Text = 'Massa 1';
            app.Massa1EditField = uieditfield(app.GridLayout11, 'numeric');
            app.Massa1EditField.ValueChangedFcn = createCallbackFcn(app, @Massa1EditFieldValueChanged, true);
            app.Massa1EditField.Value = 100;
            app.Massa2EditFieldLabel = uilabel(app.GridLayout11);
            app.Massa2EditFieldLabel.Text = 'Massa 2';
            app.Massa2EditField = uieditfield(app.GridLayout11, 'numeric');
            app.Massa2EditField.ValueChangedFcn = createCallbackFcn(app, @Massa2EditFieldValueChanged, true);
            app.Massa2EditField.Value = 100;
            app.MassaTotEditFieldLabel = uilabel(app.GridLayout11);
            app.MassaTotEditFieldLabel.Text = 'Massa Tot';
            app.MassaTotEditField = uieditfield(app.GridLayout11, 'numeric');
            app.MassaTotEditField.ValueChangedFcn = createCallbackFcn(app, @MassaTotEditFieldValueChanged, true);
            app.MassaTotEditField.Value = 100;
            app.ImpostavolumimassimiButton = uibutton(app.GridLayout11, 'push');
            app.ImpostavolumimassimiButton.ButtonPushedFcn = createCallbackFcn(app, @ImpostavolumimassimiButtonPushed, true);
            app.ImpostavolumimassimiButton.Text = 'Imposta volumi massimi';
            app.VolumiErogatiPanel = uipanel(app.GridLayout4_2);
            app.VolumiErogatiPanel.Title = 'Volumi  Erogati';
            app.GridLayout12 = uigridlayout(app.VolumiErogatiPanel);
            app.Volume1GaugeLabel = uilabel(app.GridLayout12);
            app.Volume1GaugeLabel.Text = 'Volume 1 ';
            app.Volume1Gauge = uigauge(app.GridLayout12, 'linear');
            app.Volume2GaugeLabel = uilabel(app.GridLayout12);
            app.Volume2GaugeLabel.Text = 'Volume 2';
            app.Volume2Gauge = uigauge(app.GridLayout12, 'linear');
            app.VolumetotGaugeLabel = uilabel(app.GridLayout12);
            app.VolumetotGaugeLabel.Text = 'Volume tot';
            app.VolumetotGauge = uigauge(app.GridLayout12, 'linear');
            app.ResetMassButton = uibutton(app.GridLayout12, 'push');
            app.ResetMassButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMassButtonPushed, true);
            app.ResetMassButton.Text = 'Reset Mass';
            app.Volume1EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Volume1EditField.Editable = 'off';
            app.Volume2EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Volume2EditField.Editable = 'off';
            app.Volume3EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Volume3EditField.Editable = 'off';
            app.TempoLabel = uilabel(app.GridLayout12);
            app.TempoLabel.Text = 'Tempo';
            app.TimeGauge = uigauge(app.GridLayout12, 'linear');
            app.TimeEditField = uieditfield(app.GridLayout12, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.STOPButton = uibutton(app.GridLayout12, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.BatchcompletoCheckBox = uicheckbox(app.GridLayout12);
            app.BatchcompletoCheckBox.Enable = 'off';
            app.BatchcompletoCheckBox.Text = 'Batch completo';
            app.GraficoTab = uitab(app.TabGroup);
            app.GraficoTab.Title = 'Grafico';
            app.UIAxes = uiaxes(app.GraficoTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tempo (s)')
            ylabel(app.UIAxes, 'Valore')
            zlabel(app.UIAxes, 'Z')
            app.Regressione = uitab(app.TabGroup);
            app.Regressione.Title = 'Regressione';
            app.GridLayout10 = uigridlayout(app.Regressione);
            app.SendDatasRegressionButton = uibutton(app.GridLayout10, 'push');
            app.SendDatasRegressionButton.ButtonPushedFcn = createCallbackFcn(app, @SendDatasRegressionButtonPushed, true);
            app.SendDatasRegressionButton.Text = 'Send Datas Regression';
            app.alfaLabel_2 = uilabel(app.GridLayout10);
            app.alfaLabel_2.Text = 'Kp';
            app.kp = uieditfield(app.GridLayout10, 'numeric');
            app.kp.Value = 1;
            app.alfaLabel_3 = uilabel(app.GridLayout10);
            app.alfaLabel_3.Text = 'Ki';
            app.ki = uieditfield(app.GridLayout10, 'numeric');
            app.ki.Value = 0.05;
            app.alfaLabel_4 = uilabel(app.GridLayout10);
            app.alfaLabel_4.Text = 'Kd';
            app.kd = uieditfield(app.GridLayout10, 'numeric');
            app.kd.Value = 0.25;
            app.betaLabel = uilabel(app.GridLayout10);
            app.betaLabel.Text = 'beta';
            app.Beta = uieditfield(app.GridLayout10, 'numeric');
            app.alfaLabel = uilabel(app.GridLayout10);
            app.alfaLabel.Text = 'alfa';
            app.Alfa = uieditfield(app.GridLayout10, 'numeric');
            app.Alfa.Value = 0.5;
            app.SendPIDvaluesButton = uibutton(app.GridLayout10, 'push');
            app.SendPIDvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SendPIDvaluesButtonPushed, true);
            app.SendPIDvaluesButton.Text = 'Send PID values';
            app.OPENLOOPVALUESVOLTAGEQSETalfaBetaLabel = uilabel(app.GridLayout10);
            app.OPENLOOPVALUESVOLTAGEQSETalfaBetaLabel.Text = {'OPEN LOOP VALUES:'; 'VOLTAGE = Q SET* alfa+Beta'};
            app.CLOSELOOPVALUESVOLTAGEKpeKietdtKpdedteQreadQsetLabel = uilabel(app.GridLayout10);
            app.CLOSELOOPVALUESVOLTAGEKpeKietdtKpdedteQreadQsetLabel.Text = {'CLOSE LOOP VALUES: '; 'VOLTAGE = Kp*e+ Ki∫e(t)*dt+ Kp *de/dt ; e= Qread-Qset'};
            app.Calibrazione = uitab(app.TabGroup);
            app.Calibrazione.Title = 'Calibrazione';
            app.GridLayout7 = uigridlayout(app.Calibrazione);
            app.flussimetro1Panel = uipanel(app.GridLayout7);
            app.flussimetro1Panel.Title = 'flussimetro 1';
            app.GridLayout8 = uigridlayout(app.flussimetro1Panel);
            app.StimatoEditFieldLabel = uilabel(app.GridLayout8);
            app.StimatoEditFieldLabel.Text = 'Stimato';
            app.StimatoEditField = uieditfield(app.GridLayout8, 'numeric');
            app.StimatoEditField.Editable = 'off';
            app.BilanciaEditFieldLabel = uilabel(app.GridLayout8);
            app.BilanciaEditFieldLabel.Text = 'Bilancia';
            app.BilanciaEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FattoredicorrezioneButton = uibutton(app.GridLayout8, 'push');
            app.FattoredicorrezioneButton.ButtonPushedFcn = createCallbackFcn(app, @FattoredicorrezioneButtonPushed, true);
            app.FattoredicorrezioneButton.Text = 'Fattore di correzione';
            app.K1EditFieldLabel = uilabel(app.GridLayout8);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.GridLayout8, 'numeric');
            app.CalibraButton_2 = uibutton(app.GridLayout7, 'push');
            app.CalibraButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalibraButton_2Pushed, true);
            app.CalibraButton_2.Text = 'Calibra';
            app.chronometerGauge = uigauge(app.GridLayout7, 'linear');
            app.chronometerGauge.Limits = [0 60];
            app.switch_pompa = uiswitch(app.GridLayout7, 'rocker');
            app.switch_pompa.Items = {'Pompa 1', 'Pompa 2'};
            app.switch_pompa.Value = 'Pompa 1';
            app.flussimetro2Panel = uipanel(app.GridLayout7);
            app.flussimetro2Panel.Title = 'flussimetro 2';
            app.GridLayout8_2 = uigridlayout(app.flussimetro2Panel);
            app.StimatoEditField_2Label = uilabel(app.GridLayout8_2);
            app.StimatoEditField_2Label.Text = 'Stimato';
            app.StimatoEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.StimatoEditField_2.Editable = 'off';
            app.BilanciaEditField_2Label = uilabel(app.GridLayout8_2);
            app.BilanciaEditField_2Label.Text = 'Bilancia';
            app.BilanciaEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.FattoredicorrezioneButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.FattoredicorrezioneButton_2.Text = 'Fattore di correzione';
            app.K2EditFieldLabel = uilabel(app.GridLayout8_2);
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.ExcelTab = uitab(app.TabGroup);
            app.ExcelTab.Title = 'Excel';
            app.GridLayout14 = uigridlayout(app.ExcelTab);
            app.UITable = uitable(app.GridLayout14);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.XLSButton = uibutton(app.GridLayout14, 'push');
            app.XLSButton.ButtonPushedFcn = createCallbackFcn(app, @XLSButtonPushed, true);
            app.XLSButton.Text = 'XLS';
            app.AcquisizioneDati = uibutton(app.GridLayout14, 'state');
            app.AcquisizioneDati.Text = 'Acquisisci';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLE_GUI_FLUIGENT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end